classdef P4_0812102 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        Y2                matlab.ui.control.NumericEditField
        Y2EditFieldLabel  matlab.ui.control.Label
        X2                matlab.ui.control.NumericEditField
        X2EditFieldLabel  matlab.ui.control.Label
        Y1                matlab.ui.control.NumericEditField
        Y1EditFieldLabel  matlab.ui.control.Label
        X1                matlab.ui.control.NumericEditField
        X1Label           matlab.ui.control.Label
        DeleteButton      matlab.ui.control.Button
        ChangeButton      matlab.ui.control.Button
        AddButton         matlab.ui.control.Button
        ItemsDisplay      matlab.ui.control.ListBox
        Label             matlab.ui.control.Label
        Plots             matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        squares = {};
        squares_display = {};
    end
    
    methods (Access = private)
        
        
        function draw_squares(app, selected)
            
            % clear plot
            cla(app.Plots);
            hold(app.Plots,'on')
            
            % plot every squares except the selected one
            for i = 1:numel(app.squares)
                x1 = app.squares{i}(1);
                y1 = app.squares{i}(2);
                x2 = app.squares{i}(3);
                y2 = app.squares{i}(4);
                x = [x1, x2, x2, x1, x1];
                y = [y1, y1, y2, y2, y1];
%                 disp(x) 
%                 disp(y)
                if(x1 == selected(1) && y1 == selected(2) && x2 == selected(3) && y2 == selected(4))
                    continue;  
                else
                    plot(app.Plots,x, y, 'b-'); 
                end
            end
            
            % plot the selected square
            x = [selected(1), selected(3), selected(3), selected(1), selected(1)];
            y = [selected(2), selected(2), selected(4), selected(4), selected(2)];
            plot(app.Plots,x, y, 'r-');
            hold(app.Plots,'off')
           
           
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            
            % add the value to squares
            app.squares{end + 1} = [app.X1.Value(), app.Y1.Value(), app.X2.Value(), app.Y2.Value()];
            
            % format string in list box
            str = sprintf("(%d,%d)-(%d,%d)", app.X1.Value(), app.Y1.Value(), app.X2.Value(), app.Y2.Value());
            str = convertStringsToChars(str);
            app.squares_display{end + 1} = str;
            app.ItemsDisplay.Items = app.squares_display;
            
            % indices for each of the squares
            app.ItemsDisplay.ItemsData = [1:numel(app.squares)];
            draw_squares(app, app.squares{end});
            app.ItemsDisplay.Value = numel(app.squares);
        end

        % Value changed function: ItemsDisplay
        function ItemsDisplayValueChanged(app, event)
            value = app.squares{app.ItemsDisplay.Value};
            % get current values to edit field
            app.X1.Value = value(1);
            app.Y1.Value = value(2);
            app.X2.Value = value(3);
            app.Y2.Value = value(4);
            draw_squares(app, value);
%             fprintf("value changed\n");
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            % get the index of the selected square to remove
            index = app.ItemsDisplay.Value;
            app.squares(index) = [];
            app.squares_display(index) = [];
%             fprintf("deleted\n");

            % new indices for squares
            app.ItemsDisplay.Items = app.squares_display;
            app.ItemsDisplay.ItemsData = [1:numel(app.squares)]; 
            
            if(~iscell(app.ItemsDisplay.Value))               
                value = app.squares{app.ItemsDisplay.Value};
                draw_squares(app, value);
            else
                cla(app.Plots);
            end
        end

        % Button pushed function: ChangeButton
        function ChangeButtonPushed(app, event)
            % get the index of the selected square to change
            index = app.ItemsDisplay.Value;
            
            % get new values
            newX1 = app.X1.Value;
            newY1 = app.Y1.Value;
            newX2 = app.X2.Value;
            newY2 = app.Y2.Value;           
            app.squares{index} = [newX1 newY1 newX2 newY2];
            
            % format string in list box
            str = sprintf("(%d,%d)-(%d,%d)", newX1, newY1, newX2, newY2);
            str = convertStringsToChars(str);
            app.squares_display{index} = str;
            app.ItemsDisplay.Items = app.squares_display;
            
            draw_squares(app, app.squares{index});
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Plots = uiaxes(app.UIFigure);
            title(app.Plots, {''; ''})
            xlabel(app.Plots, 'X')
            ylabel(app.Plots, 'Y')
            zlabel(app.Plots, 'Z')
            app.Plots.XLim = [0 7];
            app.Plots.YLim = [0 10];
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {''; ''};
            app.ItemsDisplay = uilistbox(app.UIFigure);
            app.ItemsDisplay.Items = {};
            app.ItemsDisplay.ValueChangedFcn = createCallbackFcn(app, @ItemsDisplayValueChanged, true);
            app.ItemsDisplay.Value = {};
            app.AddButton = uibutton(app.UIFigure, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.ChangeButton = uibutton(app.UIFigure, 'push');
            app.ChangeButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeButtonPushed, true);
            app.ChangeButton.Text = 'Change';
            app.DeleteButton = uibutton(app.UIFigure, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            app.X1Label = uilabel(app.UIFigure);
            app.X1Label.Text = 'X1: ';
            app.X1 = uieditfield(app.UIFigure, 'numeric');
            app.Y1EditFieldLabel = uilabel(app.UIFigure);
            app.Y1EditFieldLabel.Text = 'Y1: ';
            app.Y1 = uieditfield(app.UIFigure, 'numeric');
            app.X2EditFieldLabel = uilabel(app.UIFigure);
            app.X2EditFieldLabel.Text = 'X2: ';
            app.X2 = uieditfield(app.UIFigure, 'numeric');
            app.Y2EditFieldLabel = uilabel(app.UIFigure);
            app.Y2EditFieldLabel.Text = 'Y2: ';
            app.Y2 = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = P4_0812102

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end