classdef lab10 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        ImageInfo     matlab.ui.control.Label
        SelectedPath  matlab.ui.control.Label
        BrowseButton  matlab.ui.control.Button
        Directory     matlab.ui.control.ListBox
        ListBoxLabel  matlab.ui.control.Label
        Display       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        folder % selected folder
        im0 % chosen image
    end
    
    methods (Access = private)
        
        function select_file(app)
            im = imread(app.im0);
            image(app.Display, im);
            app.ImageInfo.Text = num2str(size(im));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            app.folder = uigetdir;
            app.SelectedPath.Text = app.folder;
            folderinfo = {[dir(app.folder+"\*.jpg"); dir(app.folder+"\*.png")]};
            
            images = {};
            
            for i = 1 : numel(folderinfo{1})
                images{i} = folderinfo{1}(i).name;
            end            
            app.Directory.Items = images;
            app.im0 =  app.folder + "\" + images{1};
            select_file(app);
        end

        % Value changed function: Directory
        function DirectoryValueChanged(app, event)
            value = app.Directory.Value;
            app.im0 =  app.folder + "\" + value;
            select_file(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Display = uiaxes(app.UIFigure);
            title(app.Display, 'image display')
            xlabel(app.Display, 'X')
            ylabel(app.Display, 'Y')
            zlabel(app.Display, 'Z')
            app.ListBoxLabel = uilabel(app.UIFigure);
            app.ListBoxLabel.Text = 'List Box';
            app.Directory = uilistbox(app.UIFigure);
            app.Directory.ValueChangedFcn = createCallbackFcn(app, @DirectoryValueChanged, true);
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'browse';
            app.SelectedPath = uilabel(app.UIFigure);
            app.ImageInfo = uilabel(app.UIFigure);
            app.ImageInfo.Text = 'Label2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lab10

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end