classdef lab11 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        ZoomResetButton  matlab.ui.control.Button
        ZoomOutButton    matlab.ui.control.Button
        ZoomInButton     matlab.ui.control.Button
        Panel            matlab.ui.container.Panel
        Display          matlab.ui.control.UIAxes
        ResetColor       matlab.ui.control.Button
        BlueSlider       matlab.ui.control.Slider
        BlueLabel        matlab.ui.control.Label
        GreenSlider      matlab.ui.control.Slider
        GreenLabel       matlab.ui.control.Label
        RedSlider        matlab.ui.control.Slider
        RedLabel         matlab.ui.control.Label
        ImageInfo        matlab.ui.control.Label
        SelectedPath     matlab.ui.control.Label
        BrowseButton     matlab.ui.control.Button
        Directory        matlab.ui.control.ListBox
        ListBoxLabel     matlab.ui.control.Label
    end

    
    properties (Access = private)
        folder % selected folder
        im0 % chosen image
        zoom_factor = 1;
    end
    
    methods (Access = private)
        
        function select_file(app)
            app.Directory.Enable = 1;
            app.ZoomInButton.Enable = 1;
            app.ZoomOutButton.Enable = 1;
            app.ZoomResetButton.Enable = 1;
            app.RedSlider.Enable = 1;
            app.GreenSlider.Enable = 1;
            app.BlueSlider.Enable = 1;
            app.ResetColor.Enable = 1;            
            
            im = imread(app.im0);
            show_image(app, im); 
            app.zoom_factor = 1;
            app.RedSlider.Value = 0;
            app.GreenSlider.Value = 0;
            app.BlueSlider.Value = 0;
%             fprintf("Hello\n");
%             disp(size(im2double(im)));
            app.ImageInfo.Text = num2str(size(im));
        end
        
        
        function show_image(app, im)
%             disp(app.zoom_factor)
            im = imresize(im, app.zoom_factor, 'nearest');
            w = size(im, 2);
            h = size(im, 1);
            app.Display.Position = [0 0 w h]; 
            image(app.Display, im);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Directory.Enable = 0;
            app.ZoomInButton.Enable = 0;
            app.ZoomOutButton.Enable = 0;
            app.ZoomResetButton.Enable = 0;
            app.RedSlider.Enable = 0;
            app.GreenSlider.Enable = 0;
            app.BlueSlider.Enable = 0;
            app.ResetColor.Enable = 0;
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            app.folder = uigetdir;          
            
            app.SelectedPath.Text = char(app.folder);
            folderinfo = {[dir(app.folder+"\*.jpg"); dir(app.folder+"\*.png")]};
            
            images = {};
            
            for i = 1 : numel(folderinfo{1})
                images{i} = folderinfo{1}(i).name;
            end            
            app.Directory.Items = images;
            app.im0 =  app.folder + "\" + images{1};
            select_file(app);
        end

        % Value changed function: Directory
        function DirectoryValueChanged(app, event)
            value = app.Directory.Value;
            app.im0 =  app.folder + "\" + value;
            select_file(app);
        end

        % Value changed function: RedSlider
        function RedSliderValueChanged(app, event)
            v = app.RedSlider.Value;
            im = imread(app.im0);
            r = 2^(-v);
            data = im2double(im);
            data(:, :, 1) = data(:, :, 1) .^ r;
            show_image(app, data);      
            
        end

        % Value changed function: GreenSlider
        function GreenSliderValueChanged(app, event)
            v = app.GreenSlider.Value;
            im = imread(app.im0);
            r = 2^(-v);
            data = im2double(im);
            data(:, :, 1) = data(:, :, 2) .^ r;
            show_image(app, data);
            
        end

        % Value changed function: BlueSlider
        function BlueSliderValueChanged(app, event)
            v = app.BlueSlider.Value;
            im = imread(app.im0);
            r = 2^(-v);
            data = im2double(im);
            data(:, :, 1) = data(:, :, 3) .^ r;
            show_image(app, data);
            
        end

        % Button pushed function: ResetColor
        function ResetColorButtonPushed(app, event)
            im = imread(app.im0);
            app.RedSlider.Value = 0;
            app.GreenSlider.Value = 0;
            app.BlueSlider.Value = 0;
            show_image(app, im);
        end

        % Button pushed function: ZoomInButton
        function ZoomInButtonPushed(app, event)
            app.RedSlider.Value = 0;
            app.GreenSlider.Value = 0;
            app.BlueSlider.Value = 0;
            im = imread(app.im0);
            app.zoom_factor = app.zoom_factor * 2;
            show_image(app, im)
        end

        % Button pushed function: ZoomOutButton
        function ZoomOutButtonPushed(app, event)
            app.RedSlider.Value = 0;
            app.GreenSlider.Value = 0;
            app.BlueSlider.Value = 0;
            im = imread(app.im0);
            app.zoom_factor = app.zoom_factor / 2;
            show_image(app, im)
        end

        % Button pushed function: ZoomResetButton
        function ZoomResetButtonPushed(app, event)
            app.RedSlider.Value = 0;
            app.GreenSlider.Value = 0;
            app.BlueSlider.Value = 0;
            im = imread(app.im0);
            app.zoom_factor = 1;
            show_image(app, im)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ListBoxLabel = uilabel(app.UIFigure);
            app.ListBoxLabel.Text = 'List Box';
            app.Directory = uilistbox(app.UIFigure);
            app.Directory.ValueChangedFcn = createCallbackFcn(app, @DirectoryValueChanged, true);
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'browse';
            app.SelectedPath = uilabel(app.UIFigure);
            app.ImageInfo = uilabel(app.UIFigure);
            app.ImageInfo.Text = 'Label2';
            app.RedLabel = uilabel(app.UIFigure);
            app.RedLabel.Text = 'Red';
            app.RedSlider = uislider(app.UIFigure);
            app.RedSlider.Limits = [-2 2];
            app.RedSlider.ValueChangedFcn = createCallbackFcn(app, @RedSliderValueChanged, true);
            app.GreenLabel = uilabel(app.UIFigure);
            app.GreenLabel.Text = 'Green';
            app.GreenSlider = uislider(app.UIFigure);
            app.GreenSlider.Limits = [-2 2];
            app.GreenSlider.ValueChangedFcn = createCallbackFcn(app, @GreenSliderValueChanged, true);
            app.BlueLabel = uilabel(app.UIFigure);
            app.BlueLabel.Text = 'Blue';
            app.BlueSlider = uislider(app.UIFigure);
            app.BlueSlider.Limits = [-2 2];
            app.BlueSlider.ValueChangedFcn = createCallbackFcn(app, @BlueSliderValueChanged, true);
            app.ResetColor = uibutton(app.UIFigure, 'push');
            app.ResetColor.ButtonPushedFcn = createCallbackFcn(app, @ResetColorButtonPushed, true);
            app.ResetColor.Text = 'Reset';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.Display = uiaxes(app.Panel);
            title(app.Display, 'image display')
            xlabel(app.Display, 'X')
            ylabel(app.Display, 'Y')
            zlabel(app.Display, 'Z')
            app.ZoomInButton = uibutton(app.UIFigure, 'push');
            app.ZoomInButton.ButtonPushedFcn = createCallbackFcn(app, @ZoomInButtonPushed, true);
            app.ZoomInButton.Text = 'Zoom In';
            app.ZoomOutButton = uibutton(app.UIFigure, 'push');
            app.ZoomOutButton.ButtonPushedFcn = createCallbackFcn(app, @ZoomOutButtonPushed, true);
            app.ZoomOutButton.Text = 'Zoom Out';
            app.ZoomResetButton = uibutton(app.UIFigure, 'push');
            app.ZoomResetButton.ButtonPushedFcn = createCallbackFcn(app, @ZoomResetButtonPushed, true);
            app.ZoomResetButton.Text = 'Zoom Reset';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lab11

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end